classdef HyLoop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AboutMenu                  matlab.ui.container.Menu
        DivideDataByCycleButton    matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        Panel                      matlab.ui.container.Panel
        MAButton                   matlab.ui.control.StateButton
        EFLabel                    matlab.ui.control.Label
        BSLabel                    matlab.ui.control.Label
        AnimationSlider            matlab.ui.control.Slider
        AnimationCherkBox          matlab.ui.control.CheckBox
        ButtonAnimation            matlab.ui.container.ButtonGroup
        AnimationSubButton         matlab.ui.control.RadioButton
        AnimationMainButton        matlab.ui.control.RadioButton
        AnimationLabel             matlab.ui.control.Label
        PlotLabel                  matlab.ui.control.Label
        ButtonPlot                 matlab.ui.container.ButtonGroup
        OneCycleOnlyButton         matlab.ui.control.RadioButton
        MainCurvesOnlyButton       matlab.ui.control.RadioButton
        WithCyclesButton           matlab.ui.control.RadioButton
        DependentColumnDropDown    matlab.ui.control.DropDown
        DependentColumnLabel       matlab.ui.control.Label
        CyclesLabel                matlab.ui.control.Label
        IndependentColumnLabel     matlab.ui.control.Label
        SheetLabel                 matlab.ui.control.Label
        SheetDropDown              matlab.ui.control.DropDown
        IndependentColumnDropDown  matlab.ui.control.DropDown
        CyclesListBox              matlab.ui.control.ListBox
        OpenExcelFileButton        matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file % name of opened file
        filename % excel file to open
        sheetNames % sheetNames read from excel file
        sheetData % data imported
        rowData %
        x %
        y %
        xLoopStruct %
        yLoopStruct %
        loopRecord % array record start/end row of sheetData
        xLoop %
        yLoop %
        rowLoop %
        aniManuChangingValue % slider value in manu
        loopPointPlot % tempPlot
        aniDataX % animation data
        aniDataY % animation data
    end
    
    methods (Access = private)
        
        % read data
        function readData(app)
            app.sheetData = readtable(app.filename,"Sheet",app.SheetDropDown.Value);
            app.UITable.Data = app.sheetData;
            app.rowData = size(app.sheetData,1);
        end
        
        function proc_uiEnableAfterRead(app)
            app.DivideDataByCycleButton.Enable = true;
            app.SheetDropDown.Enable = true;
            app.IndependentColumnDropDown.Enable = true;
            app.DependentColumnDropDown.Enable = true;
            app.CyclesListBox.Enable = true;
            app.OneCycleOnlyButton.Enable = true;
            app.MainCurvesOnlyButton.Enable = true;
            app.WithCyclesButton.Enable = true;
            app.AnimationCherkBox.Enable = 'on';
        end
        
        % make list
        function proc_indeColList(app)
            indeColList = app.sheetData.Properties.VariableNames;
            app.IndependentColumnDropDown.Items = indeColList;
            app.IndependentColumnDropDown.Value = indeColList{1};
            app.UITable.ColumnName = indeColList;
        end
        
        % make list
        function proc_deColList(app)
            deColList = app.sheetData.Properties.VariableNames;
            app.DependentColumnDropDown.Items = deColList;
            app.DependentColumnDropDown.Value = deColList(2);
        end
        
        % make list
        function proc_loopList(app)           
            k = 1;
            i = 1;
            while i <= app.rowData
                if i == 1
                    app.loopRecord(k,1) = i; % start_row
                else
                    app.loopRecord(k,1) = i - 1; % start_row
                end
                
                while app.x(i+1) > app.x(i)
                    i = i + 1;
                end
                app.loopRecord(k,2) = i; % max_row
                
                while app.x(i+1) < app.x(i)
                    i = i + 1;
                end
                app.loopRecord(k,3) = i; % min_row
                
                while (app.x(i) < 0) && (i < app.rowData)
                    i = i + 1;
                end
                
                if i == app.rowData
                    app.loopRecord(k,4) = i; % end_row
                    break;
                else
                    app.loopRecord(k,4) = i - 1; % end_row
                end
                
                i = i + 1;
                k = k + 1;
            end
            
            loopMax = size(app.loopRecord,1); % create looplist
            loopList = strings(1);
            for l = 1:loopMax
                loopList(l) = sprintf("Cycle_%d",l);
            end
            
            app.CyclesListBox.Items = loopList; % listBox
            app.CyclesListBox.Value = loopList(1);
        end
        
        % cycle updata
        function proc_loopUpdate(app)
            loopMax = size(app.loopRecord,1);
            for l = 1:loopMax
                app.xLoopStruct.(sprintf("Cycle_%d",l)) = app.x(app.loopRecord(l,1):app.loopRecord(l,4));
                app.yLoopStruct.(sprintf("Cycle_%d",l)) = app.y(app.loopRecord(l,1):app.loopRecord(l,4));
            end
            app.xLoop = app.xLoopStruct.(app.CyclesListBox.Value);
            app.yLoop = app.yLoopStruct.(app.CyclesListBox.Value);
        end
        
        % plot curves
        function proc_figPlot(app)
            hold(app.UIAxes,'off');
            app.x = table2array(app.sheetData(:,{app.IndependentColumnDropDown.Value}));
            app.y = table2array(app.sheetData(:,{app.DependentColumnDropDown.Value}));
            plot(app.UIAxes,app.x,app.y,"Color",[0 0.4470 0.7410]);
            app.UIAxes.Title.String = sprintf('%s-%s Curves', app.IndependentColumnDropDown.Value, app.DependentColumnDropDown.Value);
            app.UIAxes.XLabel.String = app.IndependentColumnDropDown.Value;
            app.UIAxes.YLabel.String = app.DependentColumnDropDown.Value;
        end
        
        % plot cycle
        function proc_loopPlot(app)
            proc_loopUpdate(app);
            plot(app.UIAxes,app.xLoop,app.yLoop,"Color",[0.8500 0.3250 0.0980],"LineWidth",2.0,"Marker","*","MarkerSize",4);
%             app.AnimationCherkBox.Enable = 'off'; % close animation function
        end
        
        % cycle choose botton
        function proc_loopChoose(app)
            app.CyclesListBox.Enable = 'on';
            if app.WithCyclesButton.Value == true
                proc_figPlot(app);
                hold(app.UIAxes,'on');
                proc_loopPlot(app);
            elseif app.MainCurvesOnlyButton.Value == true
                proc_figPlot(app);
                app.CyclesListBox.Enable = 'off';
            else
                hold(app.UIAxes,'off');
                proc_loopPlot(app);
            end
        end
        
        % enable animation button
        function if_aniAllorSingle(app)
            if app.AnimationCherkBox.Value == true
                if app.WithCyclesButton.Value == true
                    app.AnimationMainButton.Enable = true;
                    app.AnimationSubButton.Enable = true;
                elseif app.MainCurvesOnlyButton.Value == true
                    app.AnimationMainButton.Enable = true;
                    app.AnimationMainButton.Value = true;
                    app.AnimationSubButton.Enable = false;
                else
                    app.AnimationMainButton.Enable = false;
                    app.AnimationSubButton.Enable = true;
                    app.AnimationSubButton.Value = true;
                end
            end
        end
        
        % updata after cycle change
        function proc_animationStart(app)
            if app.AnimationCherkBox.Value == true
                proc_animationSet(app);
                hold(app.UIAxes,"on"); % one point only
                plot_aniPoint(app) % use only once
            end
            
            if app.MAButton.Value == false
            end
        end
        
        function proc_animationSet(app)
            get_aniData(app);
            
            if app.MAButton.Value == false
                app.BSLabel.Text = "B"; % Beginning / Ending
                app.EFLabel.Text = "E";
                app.rowLoop = size(app.aniDataX,1);
                app.AnimationSlider.Limits = [1 app.rowLoop];
            else
                app.BSLabel.Text = "S"; % Slower / Faster
                app.EFLabel.Text = "F";
                % todo time
                app.AnimationSlider.Limits = [10 1000];
                plot_aniAuto(app);
            end
        end
        
        % point plot / use only once!!
        function plot_aniPoint(app)
            app.loopPointPlot = plot(app.UIAxes,app.xLoop(1),app.yLoop(1),"Color",[0.4940 0.1840 0.5560],"Marker",".","MarkerSize",30); % plot once, but change later
        end
        
        % get animation data
        function get_aniData(app)
            if app.AnimationMainButton.Value == true
                % get data from main table
                app.aniDataX = app.x;
                app.aniDataY = app.y;
            else
                % get data from specific cycle
                app.aniDataX = app.xLoop;
                app.aniDataY = app.yLoop;
            end
        end
        
        function plot_aniManu(app)
            if app.MAButton.Value == false
                pointX = app.aniDataX(app.aniManuChangingValue);
                pointY = app.aniDataY(app.aniManuChangingValue);
                set (app.loopPointPlot, 'XData', pointX, 'YData', pointY)
            end
        end
        
        function plot_aniAuto(app)
            for k = 1:length(app.aniDataX)
                set (app.loopPointPlot, 'XData', app.aniDataX(k), 'YData', app.aniDataY(k));
                if app.MAButton.Value == false
                    break;
                end
                pause(1/app.AnimationSlider.Value);
            end
        end
        
        % split data by cycle
        function proc_dataSplit(app)
            loopMax = size(app.loopRecord,1);
            
            % get origin filename without extension
            dotLocations = find(app.file == '.');
            if isempty(dotLocations)
                originFileName = app.file;
            else
                originFileName = app.file(1:dotLocations(1)-1);
            end
            
            saveFilename = sprintf("%s_%s_Divided",originFileName,app.SheetDropDown.Value);
            [saveNameTemp,path] = uiputfile('*.csv','Save Data To Excel File',saveFilename);
            
            % get save name without extension
            dotLocations = find(saveNameTemp == '.');
            saveNameTemp = saveNameTemp(1:dotLocations(1)-1);
            
            for l = 1:loopMax
                saveName = sprintf("%s_Cycle_%d.csv",saveNameTemp,l);
                dataToSave = app.sheetData(app.loopRecord(l,1):app.loopRecord(l,4),:);
                fname = fullfile(path,saveName);
                writetable(dataToSave,fname);
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DivideDataByCycleButton.Enable = false;
            app.SheetDropDown.Enable = false;
            app.IndependentColumnDropDown.Enable = false;
            app.DependentColumnDropDown.Enable = false;
            app.CyclesListBox.Enable = false;
            app.OneCycleOnlyButton.Enable = false;
            app.MainCurvesOnlyButton.Enable = false;
            app.WithCyclesButton.Enable = false;
            app.AnimationCherkBox.Enable = 'off';
            app.AnimationMainButton.Enable = false;
            app.AnimationSubButton.Enable = false;
            app.AnimationSlider.Enable = 'off';
            app.MAButton.Enable = 'off';
        end

        % Button pushed function: OpenExcelFileButton
        function OpenExcelFileButtonPushed(app, event)
            app.MAButton.Value = false; % stop animation;
            [app.file,path] = uigetfile('*.xlsx','*.xls');
            if ne(app.file,0)
                proc_uiEnableAfterRead(app);
                app.filename = [path app.file];
                app.sheetNames = sheetnames(app.filename);
                app.SheetDropDown.Items = app.sheetNames; % sheet drop down list
                readData(app);
                
                proc_indeColList(app); % drop down list
                proc_deColList(app);
                
                proc_figPlot(app);
                proc_loopList(app);
                proc_loopUpdate(app);
                
                proc_animationStart(app) % update animation as well
            end
            
        end

        % Value changed function: IndependentColumnDropDown
        function IndependentColumnDropDownValueChanged(app, event)
            app.MAButton.Value = false; % stop animation;
            proc_figPlot(app);
            proc_loopUpdate(app);
            proc_animationStart(app) % update animation as well
        end

        % Value changed function: DependentColumnDropDown
        function DependentColumnDropDownValueChanged(app, event)
            app.MAButton.Value = false; % stop animation;
            proc_figPlot(app);
            proc_loopUpdate(app);
            proc_animationStart(app) % update animation as well
        end

        % Value changed function: SheetDropDown
        function SheetDropDownValueChanged(app, event)
            app.MAButton.Value = false; % stop animation;
            readData(app);
            proc_figPlot(app);
            proc_loopList(app);
            proc_loopChoose(app);
            proc_loopPlot(app);
            proc_animationStart(app) % update animation as well
        end

        % Value changed function: CyclesListBox
        function CyclesListBoxValueChanged(app, event)
            app.MAButton.Value = false; % stop animation;
            proc_loopUpdate(app);
            proc_loopChoose(app);
            if app.AnimationCherkBox.Value == true
                app.AnimationSubButton.Value = true;
            end
            proc_animationStart(app) % update animation as well
            
        end

        % Selection changed function: ButtonPlot
        function ButtonPlotSelectionChanged(app, event)
            app.MAButton.Value = false; % stop animation;
            proc_loopChoose(app);
            if_aniAllorSingle(app);
            proc_animationStart(app) % update animation as well
        end

        % Value changed function: AnimationCherkBox
        function AnimationCherkBoxValueChanged(app, event)
            app.MAButton.Value = false; % stop animation;
            value = app.AnimationCherkBox.Value;
            if value == true
                if_aniAllorSingle(app);
                app.AnimationSlider.Enable = 'on';
                app.MAButton.Enable = 'on';
                proc_animationStart(app)
            else
                app.AnimationMainButton.Enable = false;
                app.AnimationSubButton.Enable = false;
                app.AnimationSlider.Enable = 'off';
                app.MAButton.Enable = 'off';
                proc_figPlot(app);
            end
        end

        % Value changed function: MAButton
        function MAButtonValueChanged(app, event)
            proc_animationSet(app)
        end

        % Value changing function: AnimationSlider
        function AnimationSliderValueChanging(app, event)
            app.aniManuChangingValue = round(event.Value);
            plot_aniManu(app);
        end

        % Selection changed function: ButtonAnimation
        function ButtonAnimationSelectionChanged(app, event)
            app.MAButton.Value = false; % stop animation;
%             selectedButton = app.ButtonAnimation.SelectedObject;
            proc_animationSet(app);
        end

        % Button pushed function: DivideDataByCycleButton
        function DivideDataByCycleButtonPushed(app, event)
            proc_dataSplit(app)
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            fig = app.UIFigure;
            message = sprintf('Developed by Chuandong Xie \nwww.chuandongxie.com\nchuandongxie@xauat.edu.cn');
            uialert(fig,message,'About',"Icon","none");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'HyLoop';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Curves')
            xlabel(app.UIAxes, 'Variable1')
            ylabel(app.UIAxes, 'Variable2')
            app.OpenExcelFileButton = uibutton(app.UIFigure, 'push');
            app.OpenExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExcelFileButtonPushed, true);
            app.OpenExcelFileButton.Text = 'Open Excel File';
            app.Panel = uipanel(app.UIFigure);
            app.CyclesListBox = uilistbox(app.Panel);
            app.CyclesListBox.Items = {};
            app.CyclesListBox.ValueChangedFcn = createCallbackFcn(app, @CyclesListBoxValueChanged, true);
            app.CyclesListBox.Value = {};
            app.IndependentColumnDropDown = uidropdown(app.Panel);
            app.IndependentColumnDropDown.Items = {};
            app.IndependentColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @IndependentColumnDropDownValueChanged, true);
            app.IndependentColumnDropDown.Value = {};
            app.SheetDropDown = uidropdown(app.Panel);
            app.SheetDropDown.Items = {};
            app.SheetDropDown.ValueChangedFcn = createCallbackFcn(app, @SheetDropDownValueChanged, true);
            app.SheetDropDown.Value = {};
            app.SheetLabel = uilabel(app.Panel);
            app.SheetLabel.Text = 'Sheet';
            app.IndependentColumnLabel = uilabel(app.Panel);
            app.IndependentColumnLabel.Text = 'Independent Column';
            app.CyclesLabel = uilabel(app.Panel);
            app.CyclesLabel.Text = 'Cycles';
            app.DependentColumnLabel = uilabel(app.Panel);
            app.DependentColumnLabel.Text = 'Dependent Column';
            app.DependentColumnDropDown = uidropdown(app.Panel);
            app.DependentColumnDropDown.Items = {};
            app.DependentColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @DependentColumnDropDownValueChanged, true);
            app.DependentColumnDropDown.Value = {};
            app.ButtonPlot = uibuttongroup(app.Panel);
            app.ButtonPlot.SelectionChangedFcn = createCallbackFcn(app, @ButtonPlotSelectionChanged, true);
            app.WithCyclesButton = uiradiobutton(app.ButtonPlot);
            app.WithCyclesButton.Text = 'With Cycles';
            app.WithCyclesButton.Value = true;
            app.MainCurvesOnlyButton = uiradiobutton(app.ButtonPlot);
            app.MainCurvesOnlyButton.Text = 'Main Curves Only';
            app.OneCycleOnlyButton = uiradiobutton(app.ButtonPlot);
            app.OneCycleOnlyButton.Text = 'One Cycle Only';
            app.PlotLabel = uilabel(app.Panel);
            app.PlotLabel.Text = 'Plot';
            app.AnimationLabel = uilabel(app.Panel);
            app.AnimationLabel.Text = 'Animation';
            app.ButtonAnimation = uibuttongroup(app.Panel);
            app.ButtonAnimation.SelectionChangedFcn = createCallbackFcn(app, @ButtonAnimationSelectionChanged, true);
            app.AnimationMainButton = uiradiobutton(app.ButtonAnimation);
            app.AnimationMainButton.Text = 'Main Curves';
            app.AnimationMainButton.Value = true;
            app.AnimationSubButton = uiradiobutton(app.ButtonAnimation);
            app.AnimationSubButton.Text = 'Cycle Only';
            app.AnimationCherkBox = uicheckbox(app.Panel);
            app.AnimationCherkBox.ValueChangedFcn = createCallbackFcn(app, @AnimationCherkBoxValueChanged, true);
            app.AnimationCherkBox.Text = '';
            app.AnimationSlider = uislider(app.Panel);
            app.AnimationSlider.Limits = [0 1];
            app.AnimationSlider.MajorTicks = [];
            app.AnimationSlider.MajorTickLabels = {''};
            app.AnimationSlider.ValueChangingFcn = createCallbackFcn(app, @AnimationSliderValueChanging, true);
            app.AnimationSlider.MinorTicks = [];
            app.BSLabel = uilabel(app.Panel);
            app.BSLabel.Text = 'B/S';
            app.EFLabel = uilabel(app.Panel);
            app.EFLabel.Text = 'E/F';
            app.MAButton = uibutton(app.Panel, 'state');
            app.MAButton.ValueChangedFcn = createCallbackFcn(app, @MAButtonValueChanged, true);
            app.MAButton.Text = 'M/A';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DivideDataByCycleButton = uibutton(app.UIFigure, 'push');
            app.DivideDataByCycleButton.ButtonPushedFcn = createCallbackFcn(app, @DivideDataByCycleButtonPushed, true);
            app.DivideDataByCycleButton.Text = 'Divide Data By Cycle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HyLoop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end